classdef NeuroPatchbay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroPatchbayUIFigure  matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        NewPatchbayMenu        matlab.ui.container.Menu
        SavePatchbayMenu       matlab.ui.container.Menu
        LoadPatchbayMenu       matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        Tree                   matlab.ui.container.Tree
        PaletteLabel           matlab.ui.control.Label
        ComponentsLabel        matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        ObjectContextMenu      matlab.ui.container.ContextMenu
        DeleteMenu             matlab.ui.container.Menu
        UIAxesContextMenu      matlab.ui.container.ContextMenu
        ResetViewMenu          matlab.ui.container.Menu
        ViewAllObjectsMenu     matlab.ui.container.Menu
    end


    properties (Access = private)
        PatchbayGraph
        ObjectList = struct
        ObjectImage
        ObjectSubgraphPlot
        ObjectGraphPlot
        SelectedObject
        SelectedObjectTag
        LastObject
        DragPoint
        AddingEdge
        CurrentEdge
        HomeAxes
        SelectionBox
    end

    properties (Access = public)
        DynamicTree
    end

    methods (Access = private)

        %% Tree Methods
        % Constructors
        function buildComponentsTreeFromAssets(app,appPath)
            componentDirectories = fullfile(appPath,'assets','Components');
            ls = dir(componentDirectories);
            idx = strcmp('.',{ls.name}) | strcmp('..',{ls.name});
            ls(idx) = [];
            count = 0;
            for i = 1:numel(ls)
                count = count + 1;
                holdCount = count;
                app.DynamicTree.(['TreeNode',int2str(holdCount)]) = uitreenode(app.Tree);
                app.DynamicTree.(['TreeNode',int2str(holdCount)]).Text = ls(i).name;

                ls1 = dir(fullfile(ls(i).folder,ls(i).name));
                ls1([ls1.isdir]) = [];
                for j = 1:numel(ls1)
                    jsonFile = fullfile(ls1(j).folder,ls1(j).name);
                    jsonText = fileread(jsonFile);
                    jsonStruct = jsondecode(jsonText);
                    count = count + 1;
                    app.DynamicTree.(['TreeNode',int2str(count)]) = uitreenode(app.DynamicTree.(['TreeNode',int2str(holdCount)]));
                    app.DynamicTree.(['TreeNode',int2str(count)]).Text = jsonStruct.Name;
                    app.DynamicTree.(['TreeNode',int2str(count)]).UserData = jsonStruct;
                    app.DynamicTree.(['TreeNode',int2str(count)]).Icon = fullfile(appPath,'assets','AppIcons','electricity-256.png');
                end
            end
            app.Tree.DoubleClickedFcn = @app.treeNodeSelected;
        end

        % Callbacks
        function treeNodeSelected(app,~,event)
            if event.InteractionInformation.Level == 2
                addObjectToPalette(app,event.InteractionInformation.Node)
            end
        end

        %% Object Methods
        % Constructors
        function addObjectToPalette(app,source)
            newObjectName = createObjectName(app,source);
            addObjectToList(app,source,newObjectName);
            addObjectImageToPalette(app,source,newObjectName);
            addObjectSubgraphToPalette(app,source,newObjectName);
            addNodeToPatchbayGraph(app,newObjectName);
        end

        function newObjectName = createObjectName(app,source)
            objectName = source.Text;
            count = 1;
            tmpName = [objectName,int2str(count)];
            while ismember(tmpName,fieldnames(app.ObjectList))
                count = count + 1;
                tmpName = regexprep(tmpName,'\d+$',int2str(count));
            end
            newObjectName = tmpName;
        end

        function addObjectToList(app,source,objectName)
            app.ObjectList.(objectName) = source.UserData;
        end

        function addObjectImageToPalette(app,source,objectName)
            icnMat = imread(source.Icon);
            icnMat = flip(icnMat,1);
            xyCenter = size(icnMat,1,2)./2;
            viewCenter = sum([xlim(app.UIAxes);ylim(app.UIAxes)],2)/2;
            app.ObjectImage = image(app.UIAxes,icnMat,'XData',[-xyCenter(1) xyCenter(1)]+viewCenter(1),'YData',[-xyCenter(2) xyCenter(2)]+viewCenter(2),'ButtonDownFcn',@app.selectObject,'Tag',objectName);
            app.ObjectImage.ContextMenu = app.ObjectContextMenu;
        end

        function addObjectSubgraphToPalette(app,source,objectName)
            Ports = struct;
            try
                inputNodeNumber = source.UserData.Connections.Inputs.Neural.Number;
                inputSignalsPerNode = source.UserData.Connections.Inputs.Neural.SignalsPerConnector;
                inputNodeIndices = cell(inputNodeNumber,1);
                for i = 1:inputNodeNumber
                    inputNodeIndices{i} = (1:inputSignalsPerNode)+inputSignalsPerNode*(i-1);
                end
                Ports.InputNodes = 1:inputNodeNumber;
                Ports.InputIndices = inputNodeIndices;
            catch
                inputNodeNumber = 0;
            end
            try
                outputNodeNumber = source.UserData.Connections.Outputs.Neural.Number;
                outputSignalsPerNode = source.UserData.Connections.Inputs.Neural.SignalsPerConnector;
                outputNodeIndices = cell(outputNodeNumber,1);
                for i = 1:outputNodeNumber
                    outputNodeIndices{i} = (1:outputSignalsPerNode)+outputSignalsPerNode*(i-1);
                end
                Ports.OutputNodes = (1:outputNodeNumber)+inputNodeNumber;
                Ports.OutputIndices = outputNodeIndices;
            catch
                outputNodeNumber = 0;
            end

            A = zeros(inputNodeNumber+outputNodeNumber);
            B = zeros(outputNodeNumber,inputNodeNumber);
            if isempty(B)
            elseif min(size(B))==1
                B = B + 1;
            elseif size(B,1)==size(B,2)
                B = eye(size(B));
            end
            A(inputNodeNumber+1:size(A,1),1:size(B,2)) = B;
            A = A+A';
            subgraph = digraph(triu(A));

            spacing = linspace(app.ObjectImage.XData(1),app.ObjectImage.XData(2),inputNodeNumber+2);
            spacing([1 numel(spacing)]) = [];
            xdata = spacing;
            ydata = repmat(app.ObjectImage.YData(1),1,inputNodeNumber);
            markerArray = repmat("v",1,inputNodeNumber);

            spacing = linspace(app.ObjectImage.XData(1),app.ObjectImage.XData(2),outputNodeNumber+2);
            spacing([1 numel(spacing)]) = [];
            xdata = [xdata spacing];
            ydata = [ydata repmat(app.ObjectImage.YData(2),1,outputNodeNumber)];
            markerArray = [markerArray repmat("^",1,outputNodeNumber)];

            app.ObjectSubgraphPlot = plot(app.UIAxes,subgraph,'XData',xdata,'YData',ydata);
            app.ObjectSubgraphPlot.ButtonDownFcn = @app.selectObjectPort;
            app.ObjectSubgraphPlot.NodeColor = [0 1 0];
            app.ObjectSubgraphPlot.EdgeColor = [0 1 0];
            app.ObjectSubgraphPlot.NodeLabel = {};
            app.ObjectSubgraphPlot.Marker = markerArray;
            app.ObjectSubgraphPlot.MarkerSize = 10;
            app.ObjectSubgraphPlot.Tag = objectName;
            app.ObjectSubgraphPlot.UserData = Ports;
            uistack(app.ObjectSubgraphPlot,'bottom')
        end

        function addNodeToPatchbayGraph(app,objectName)
            app.PatchbayGraph = addnode(app.PatchbayGraph,objectName);
        end

        function addCurrentEdgeToPalette(app,targetSubgraph,targetNodeOfSubgraph)
            tmp = {app.CurrentEdge.Tag,targetSubgraph.Tag};
            inputPortSelected = ismember(targetNodeOfSubgraph,targetSubgraph.UserData.InputNodes);
            if ~inputPortSelected
                tmp = flip(tmp);
            end
            movingNode = 2 - ~inputPortSelected;
            app.CurrentEdge.Tag = strjoin(tmp,'-');
            app.CurrentEdge.XData(movingNode) = targetSubgraph.XData(targetNodeOfSubgraph);
            app.CurrentEdge.YData(movingNode) = targetSubgraph.YData(targetNodeOfSubgraph);
            app.CurrentEdge.PickableParts = 'all';
            app.CurrentEdge.ButtonDownFcn = @app.selectEdge;
        end

        function addEdgeToPatchbayGraph(app)
        app.PatchbayGraph = addedge(app.PatchbayGraph,[]);
        end

        % Destructors
        function deleteSelectedObject(app)
            if isempty(app.SelectedObjectTag)
                return
            end
            objectName = app.SelectedObjectTag;
            removeObjectFromPalette(app,objectName);
            removeEdgesConnectedToObject(app,objectName);
            removeObjectFromList(app,objectName);
            removeNodeFromPatchbayGraph(app,objectName);
        end

        function removeObjectFromPalette(app,objectName)
            for i = 1:numel(objectName)
                objectHandle = findobj(app.UIAxes.Children,'Tag',objectName{i});
                delete(objectHandle)
            end
        end

        function removeEdgesConnectedToObject(app,objectName)
            for i = 1:numel(objectName)
                edgeHandle = findobj(app.UIAxes.Children,'-regexp','Tag',objectName{i});
                delete(edgeHandle)
            end
        end

        function removeObjectFromList(app,objectName)
            for i = 1:numel(objectName)
                app.ObjectList = rmfield(app.ObjectList,objectName{i});
            end
        end

        function removeNodeFromPatchbayGraph(app,objectName)
            for i = 1:numel(objectName)
                app.PatchbayGraph = rmnode(app.PatchbayGraph,objectName{i});
            end
        end

        function deleteCurrentEdgeFromPalette(app)
            delete(app.CurrentEdge)
        end

        % Gets
        function h = getObject(app,source)
            h = findobj(app.UIAxes.Children,'Selected','on');
            for i = 1:numel(h)
                h(i).Selected = 'off';
            end
            switch app.NeuroPatchbayUIFigure.SelectionType
                case 'normal'
                    app.SelectedObjectTag = {source.Tag};
                case 'alt'
                    app.SelectedObjectTag = unique([app.SelectedObjectTag,{source.Tag}]);
            end
            h = [];
            for i = 1:numel(app.SelectedObjectTag)
                h = [h; findobj(app.UIAxes.Children,'Tag',app.SelectedObjectTag{i})];
            end
        end

        function objectName = getObjectName(~,source)
            objectName = source.Tag;
        end

        % Sets
        function renameObjectInList(app,oldName,newName)
            app.ObjectList.(newName) = app.ObjectList.(oldName);
            app.ObjectList = rmfield(app.ObjectList,oldName);
        end

        function editEdgePropertyBetweenObjects(app)

        end

        % Callbacks
        function selectObject(app,source,event)
            checkButtonPress(app,event);
            if ismember(event.Button, 2)
                return
            end
            if app.AddingEdge
                cancelAddingEdge(app)
            end
            app.SelectedObject = getObject(app,source);
            ind = isgraphics(app.SelectedObject,'Image');
            selectedObjectImages = app.SelectedObject(ind);
            set(selectedObjectImages,'Selected','on')
            selectedEdges = findobj(app.UIAxes.Children,'-regexp','Tag','-');
            selectedEdgeNodes = zeros(numel(selectedEdges),2);
            for i = 1:numel(selectedEdges)
                for j = 1:numel(app.SelectedObjectTag)
                    tmp = regexp(selectedEdges(i).Tag,app.SelectedObjectTag{j});
                    selectedEdgeNodes(i,(tmp>1)+1) = 1;
                end
            end
            app.DragPoint = event.IntersectionPoint(1:2);
            app.NeuroPatchbayUIFigure.Pointer = 'fleur';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = {@app.dragObject,app.SelectedObject,selectedEdges,selectedEdgeNodes};
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = {@app.dragObjectDone};
        end

        function deselectObject(app)
            h = findobj(app.UIAxes.Children,'Selected','on');
            for i = 1:numel(h)
                h(i).Selected = 'off';
            end
            if app.AddingEdge
                cancelAddingEdge(app)
            end
        end

        function dragObject(app,~,~,selectedObjects,selectedEdges,selectedEdgeNodes)
            moveDifference = app.UIAxes.CurrentPoint(1,1:2) - app.DragPoint;
            for i = 1:numel(selectedObjects)
                selectedObjects(i).XData = selectedObjects(i).XData + moveDifference(1);
                selectedObjects(i).YData = selectedObjects(i).YData + moveDifference(2);
            end
            for i = 1:numel(selectedEdges)
                if selectedEdgeNodes(i,1)
                    selectedEdges(i).XData(1) = selectedEdges(i).XData(1) + moveDifference(1);
                    selectedEdges(i).YData(1) = selectedEdges(i).YData(1) + moveDifference(2);
                end
                if selectedEdgeNodes(i,2)
                    selectedEdges(i).XData(2) = selectedEdges(i).XData(2) + moveDifference(1);
                    selectedEdges(i).YData(2) = selectedEdges(i).YData(2) + moveDifference(2);
                end
            end
            app.DragPoint = app.UIAxes.CurrentPoint(1,1:2);
        end

        function selectObjectPort(app,source,event)
            checkButtonPress(app,event);
            if ismember(event.Button, 2)
                return
            end
            [~,idx] = min(sum(([source.XData;source.YData]'-event.IntersectionPoint(1:2)).^2,2).^(1/2));
            inputPortSelected = ismember(idx,source.UserData.InputNodes);
            if app.AddingEdge
                if ~strcmp(source.Tag,app.CurrentEdge.Tag) && app.CurrentEdge.UserData~=inputPortSelected
                    addCurrentEdgeToPalette(app,source,idx)
                    dragObjectDone(app)
                    app.AddingEdge = 0;
                    return
                else
                    cancelAddingEdge(app)
                end
            end
            edgeGraph = digraph(1,2);
            app.CurrentEdge = plot(app.UIAxes,edgeGraph,'XData',repmat(source.XData(idx),1,2),'YData',repmat(source.YData(idx),1,2),'PickableParts','none');
            app.CurrentEdge.Marker = 'o';
            app.CurrentEdge.MarkerSize = 12;
            app.CurrentEdge.NodeColor = [1 0 0];
            app.CurrentEdge.NodeLabel = {};
            app.CurrentEdge.EdgeColor = [1 0 0];
            app.CurrentEdge.LineWidth = 3;
            app.CurrentEdge.ArrowSize = 15;
            app.CurrentEdge.ArrowPosition = 0.7;
            app.CurrentEdge.Tag = source.Tag;
            app.CurrentEdge.UserData = inputPortSelected;
            movingNode = 2 - inputPortSelected;
            uistack(app.CurrentEdge,'top')
            app.AddingEdge = 1;
            app.NeuroPatchbayUIFigure.Pointer = 'crosshair';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = {@app.dragNode,app.CurrentEdge,movingNode};
        end

        function selectEdge(app,source,event)
            checkButtonPress(app,event);
            if ismember(event.Button, 2)
                return
            end
            [~,selectedNode] = min(sum(([source.XData;source.YData]'-event.IntersectionPoint(1:2)).^2,2).^(1/2));
            app.CurrentEdge = source;
            tags = strsplit(app.CurrentEdge.Tag,'-');
            app.CurrentEdge.Tag = regexprep(app.CurrentEdge.Tag,tags{selectedNode},'');
            app.CurrentEdge.Tag = regexprep(app.CurrentEdge.Tag,'-','');
            app.CurrentEdge.PickableParts = 'none';
            app.AddingEdge = 1;
            app.NeuroPatchbayUIFigure.Pointer = 'crosshair';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = {@app.dragNode,app.CurrentEdge,selectedNode};
        end

        function dragNode(app,~,~,callingSource,idx)
            callingSource.XData(idx) = app.UIAxes.CurrentPoint(1,1);
            callingSource.YData(idx) = app.UIAxes.CurrentPoint(1,2);
        end

        function cancelAddingEdge(app)
            deleteCurrentEdgeFromPalette(app)
            dragObjectDone(app)
            app.AddingEdge = 0;
        end

        function dragObjectDone(app,~,~)
            app.NeuroPatchbayUIFigure.Pointer = 'arrow';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = [];
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = [];
        end

        function panAxes(app,~,~)
            moveDifference = app.UIAxes.CurrentPoint(1,1:2) - app.DragPoint;
            xlim(app.UIAxes,xlim(app.UIAxes)-moveDifference(1))
            ylim(app.UIAxes,ylim(app.UIAxes)-moveDifference(2))
            app.DragPoint = app.UIAxes.CurrentPoint(1,1:2);
        end

        function drawSelectionBox(app,~,~)
            currentPoint = app.UIAxes.CurrentPoint(1,1:2);
            app.SelectionBox.XData(2:3) = currentPoint(1);
            app.SelectionBox.YData(3:4) = currentPoint(2);
        end

        function selectionBoxDrawn(app,~,~)
            selectionShape = polyshape(app.SelectionBox.XData,app.SelectionBox.YData);
            delete(app.SelectionBox)
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = [];
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = [];
            if isempty(selectionShape.Vertices)
                return
            end
            selectionTags = [];
            h = findobj(app.UIAxes.Children,'Type','Image');
            for i = 1:numel(h)
                if all(isinterior(selectionShape,h(i).XData,h(i).YData))
                    selectionTags = [selectionTags {h(i).Tag}];
                end
            end
            selectionTags = unique(selectionTags);
            h = [];
            for i = 1:numel(selectionTags)
                h = [h;findobj(app.UIAxes.Children,'Tag',selectionTags{i})];
            end
            app.SelectedObject = h;
            ind = isgraphics(app.SelectedObject,'Image');
            selectedObjectImages = app.SelectedObject(ind);
            set(selectedObjectImages,'Selected','on')
        end

        function checkButtonPress(app,event)
            switch event.Button
                case 1
                    if ~isgraphics(event.Source,'axes')
                        return
                    end
                    app.SelectionBox = line(app.UIAxes,'XData',repmat(event.IntersectionPoint(1,1),1,5),'YData',repmat(event.IntersectionPoint(1,2),1,5));
                    app.SelectionBox.Color = [0 0 1];
                    app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.drawSelectionBox;
                    app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.selectionBoxDrawn;
                case 2
                    app.DragPoint = event.IntersectionPoint(1:2);
                    app.NeuroPatchbayUIFigure.Pointer = 'hand';
                    app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.panAxes;
                    app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.dragObjectDone;
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NeuroPatchbayUIFigure.Visible = 'off';
            
            axis(app.UIAxes,'square')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Toolbar = [];
            app.HomeAxes = [xlim(app.UIAxes);ylim(app.UIAxes)];

            pathToMLAPP = fileparts(mfilename('fullpath'));
            buildComponentsTreeFromAssets(app,pathToMLAPP)

            drawnow
            app.NeuroPatchbayUIFigure.Visible = 'on';
        end

        % Window key press function: NeuroPatchbayUIFigure
        function NeuroPatchbayUIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
                case 'delete'
                    if app.AddingEdge
                        cancelAddingEdge(app)
                    else
                        deleteSelectedObject(app)
                    end
            end
        end

        % Menu selected function: DeleteMenu
        function deleteSelectedObjectCallback(app, event)
            deleteSelectedObject(app)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            deselectObject(app)
            checkButtonPress(app,event)
            
        end

        % Window scroll wheel function: NeuroPatchbayUIFigure
        function NeuroPatchbayUIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            a = xlim(app.UIAxes);
            xlim(app.UIAxes,a+a*0.10*verticalScrollCount)
            ylim(app.UIAxes,a+a*0.10*verticalScrollCount)
        end

        % Menu selected function: NewPatchbayMenu
        function NewPatchbayMenuSelected(app, event)
            cla(app.UIAxes)

            axis(app.UIAxes,'square')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Toolbar = [];
            xlim(app.UIAxes,app.HomeAxes(1,:))
            ylim(app.UIAxes,app.HomeAxes(2,:))
        end

        % Menu selected function: ViewAllObjectsMenu
        function ViewAllObjectsMenuSelected(app, event)
            xMin = inf;
            xMax = -inf;
            yMin = inf;
            yMax = -inf;
            h = findobj(app.UIAxes.Children,'flat','-property','XData');
            for i = 1:numel(h)
                xMin = min([xMin h(i).XData]);
                xMax = max([xMax h(i).XData]);
                yMin = min([yMin h(i).YData]);
                yMax = max([yMax h(i).YData]);
            end
            boundingBox = [xMin,xMax;yMin,yMax];
            [~,ind] = max(diff(boundingBox,1,2));
            maxLimit = diff(boundingBox(ind,:))*1.1/2;
            midPoint = sum(boundingBox,2)./2;
            xlim(app.UIAxes,[midPoint(1)-maxLimit midPoint(1)+maxLimit])
            ylim(app.UIAxes,[midPoint(2)-maxLimit midPoint(2)+maxLimit])
        end

        % Menu selected function: ResetViewMenu
        function ResetViewMenuSelected(app, event)
            xlim(app.UIAxes,app.HomeAxes(1,:))
            ylim(app.UIAxes,app.HomeAxes(2,:))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroPatchbayUIFigure = uifigure('Visible', 'off');
            app.NeuroPatchbayUIFigure.Name = 'NeuroPatchbay';
            app.NeuroPatchbayUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @NeuroPatchbayUIFigureWindowScrollWheel, true);
            app.NeuroPatchbayUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @NeuroPatchbayUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.NeuroPatchbayUIFigure);
            app.FileMenu.Text = 'File';
            app.NewPatchbayMenu = uimenu(app.FileMenu);
            app.NewPatchbayMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPatchbayMenuSelected, true);
            app.NewPatchbayMenu.Text = 'New Patchbay';
            app.SavePatchbayMenu = uimenu(app.FileMenu);
            app.SavePatchbayMenu.Separator = 'on';
            app.SavePatchbayMenu.Text = 'Save Patchbay';
            app.LoadPatchbayMenu = uimenu(app.FileMenu);
            app.LoadPatchbayMenu.Text = 'Load Patchbay';
            app.GridLayout = uigridlayout(app.NeuroPatchbayUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ComponentsLabel = uilabel(app.GridLayout);
            app.ComponentsLabel.Text = 'Components';
            app.PaletteLabel = uilabel(app.GridLayout);
            app.PaletteLabel.Text = 'Palette';
            app.Tree = uitree(app.GridLayout);
            app.ObjectContextMenu = uicontextmenu(app.NeuroPatchbayUIFigure);
            app.DeleteMenu = uimenu(app.ObjectContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSelectedObjectCallback, true);
            app.DeleteMenu.Text = 'Delete';
            app.UIAxesContextMenu = uicontextmenu(app.NeuroPatchbayUIFigure);
            app.ResetViewMenu = uimenu(app.UIAxesContextMenu);
            app.ResetViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenuSelected, true);
            app.ResetViewMenu.Text = 'Reset View';
            app.ViewAllObjectsMenu = uimenu(app.UIAxesContextMenu);
            app.ViewAllObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewAllObjectsMenuSelected, true);
            app.ViewAllObjectsMenu.Text = 'View All Objects';
            % Assign app.UIAxesContextMenu
            app.UIAxes.ContextMenu = app.UIAxesContextMenu;
            % Show the figure after all components are created
            app.NeuroPatchbayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroPatchbay

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeuroPatchbayUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeuroPatchbayUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroPatchbayUIFigure)
        end
    end
end